classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        FLUIDFLOWDETAILSLabel       matlab.ui.control.Label
        ComputationStatusLampLabel  matlab.ui.control.Label
        ComputationStatusLamp       matlab.ui.control.Lamp
        INPUTPanel                  matlab.ui.container.Panel
        AOAEditFieldLabel           matlab.ui.control.Label
        AOAEditField                matlab.ui.control.NumericEditField
        VinfEditFieldLabel          matlab.ui.control.Label
        VinfEditField               matlab.ui.control.NumericEditField
        NACAEditFieldLabel          matlab.ui.control.Label
        NACAEditField               matlab.ui.control.NumericEditField
        NoofPanelsEditFieldLabel    matlab.ui.control.Label
        NoofPanelsEditField         matlab.ui.control.NumericEditField
        ComputeButton               matlab.ui.control.Button
        ClearButton                 matlab.ui.control.Button
        CenterPanel                 matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes3                     matlab.ui.control.UIAxes
        UIAxes4                     matlab.ui.control.UIAxes
        RightPanel                  matlab.ui.container.Panel
        ANALYSISRESULTSLabel        matlab.ui.control.Label
        ResultsPanel                matlab.ui.container.Panel
        CLEditFieldLabel            matlab.ui.control.Label
        CLEditField                 matlab.ui.control.NumericEditField
        CMEditFieldLabel            matlab.ui.control.Label
        CMEditField                 matlab.ui.control.NumericEditField
        DownloadsPanel              matlab.ui.container.Panel
        PanelCoordinatesButton      matlab.ui.control.Button
        VorticityMatrixButton       matlab.ui.control.Button
        CpMatrixButton              matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        sum=0; % Description
    end
    
    methods (Access = public)
        
        
function [dataX,dataY] = LOAD_AIRFOIL_SELIG(app,fileName)

hdrlns = 1;
if (strcmp(fileName,'nasasc2-0714'))
    hdrlns = 3;
elseif (strcmp(fileName,'s1020'))
    hdrlns = 2;
end

fidAirfoil = fopen(['./Airfoil_DAT_Selig/' fileName '.dat']);               
dataBuffer = textscan(fidAirfoil,'%f %f','CollectOutput',1,...              
                                         'HeaderLines',hdrlns,...
                                         'Delimiter','');
dataX = dataBuffer{1}(:,1);                                                 
dataY = dataBuffer{1}(:,2);                                                 
fclose(fidAirfoil); 

% Delete any duplicate (0,0) lines (only need one)
dataArr     = [dataX dataY];
[~,ia,~]    = unique(dataArr,'rows','stable');                              
i           = true(size(dataArr,1),1);                                      
i(ia)       = false;                                                        
dataArr(i,:)= [];                                                           
dataX       = dataArr(:,1);                                               
dataY       = dataArr(:,2);                                               

% Close the airfoil by adding final point
if (dataY(1) ~= dataY(end))                                                 
    dataX(end+1) = dataX(1);                                                
    dataY(end+1) = dataY(1);                                                
end
end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            load = 'Airfoil';                                                            
            AoA  = app.AOAEditField.Value;
            Vinf = app.VinfEditField.Value; 
            fileName = 'naca2420';
            aoa=app.AOAEditField.Value;
            vinf=app.VinfEditField.Value;
            %app.sumEditField.Value=aoa+vinf;
            
           % [XB,YB]  = LOAD_AIRFOIL_SELIG(app,fileName);


            flagAirfoil.XFoilCreate = 1;                                                
            flagAirfoil.XFoilLoad   = 0;   
            
            
flagPlot = [0;          % Airfoil with panel normal vectors
            0;          % Geometry boundary pts, control pts, first panel, second panel
            1;          % Cp vectors at airfoil surface panels
            1;          % Pressure coefficient comparison (XFOIL vs. VPM)
            0;          % Airfoil streamlines
            1];         % Pressure coefficient contour


            %Vinf = 1;                                                                   
            %AoA  = 0; 
            nn=app.NACAEditField.Value;
            pp=app.NoofPanelsEditField.Value;
            NACA = num2str(nn);                                                              
            alpha = AoA*(pi/180);  
            
            PPAR.N  = num2str(pp);                                                            % "Number of panel nodes"
            PPAR.P  = '4';                                                              % "Panel bunching parameter"
            PPAR.T  = '1.5';                                                            % "TE/LE panel density ratios"
            PPAR.R  = '1';                                                              % "Refined area/LE panel density ratio"
            PPAR.XT = '1 1';                                                            % "Top side refined area x/c limits"
            PPAR.XB = '1 1';
            
            
            [xFoilResults,success] = XFOIL(NACA,PPAR,AoA,flagAirfoil);                  % Get XFOIL results for prescribed airfoil

            afName  = xFoilResults.afName;                                              % Airfoil name
xFoilX  = xFoilResults.X;                                                   % X-coordinate for Cp result
xFoilY  = xFoilResults.Y;                                                   % Y-coordinate for Cp result
xFoilCP = xFoilResults.CP;                                                  % Pressure coefficient
XB      = xFoilResults.XB;                                                  % Boundary point X-coordinate
YB      = xFoilResults.YB;                                                  % Boundary point Y-coordinate
xFoilCL = xFoilResults.CL;                                                  % Lift coefficient
xFoilCD = xFoilResults.CD;                                                  % Drag coefficient
xFoilCM = xFoilResults.CM;                                                  % Moment coefficient


numPts = length(XB);                                                        
numPan = numPts - 1;                                                        
plot(app.UIAxes,XB,YB,'ko-');

%% CHECK PANEL DIRECTIONS - FLIP IF NECESSARY

% Check for direction of points
edge = zeros(numPan,1);                                                     
for i = 1:1:numPan                                                          
    edge(i) = (XB(i+1)-XB(i))*(YB(i+1)+YB(i));                              
end
sumEdge = sum(edge);                                                        
% If panels are CCW, flip them (don't if CW)
if (sumEdge < 0)                                                            
    XB = flipud(XB);                                                        
    YB = flipud(YB);                                                        
end

%% PANEL METHOD GEOMETRY - REF [1]

% Initialize variables
XC   = zeros(numPan,1);                                                    
YC   = zeros(numPan,1);                                                    
Cp_x=zeros(numPan,1);
S    = zeros(numPan,1);                                                    
phiD = zeros(numPan,1);                                                    

% Find geometric quantities of the airfoil
for i = 1:1:numPan                                                         
    XC(i)   = 0.5*(XB(i)+XB(i+1));                                         
    YC(i)   = 0.5*(YB(i)+YB(i+1));                                         
    dx      = XB(i+1)-XB(i);                                               
    dy      = YB(i+1)-YB(i);                                               
    S(i)    = (dx^2 + dy^2)^0.5;                                           
	phiD(i) = atan2d(dy,dx);                                               
    if (phiD(i) < 0)                                                       
        phiD(i) = phiD(i) + 360;
    end
end


deltaD             = phiD + 90;                                             
betaD              = deltaD - AoA;                                          
betaD(betaD > 360) = betaD(betaD > 360) - 360;                              

phi  = phiD.*(pi/180);                                                      
beta = betaD.*(pi/180);                                                     

%% COMPUTE VORTEX PANEL STRENGTHS - REF [5]


[K,L] = COMPUTE_KL_VPM(XC,YC,XB,YB,phi,S);                                  % Compute geometric integrals


A = zeros(numPan,numPan);                                                   
for i = 1:1:numPan                                                          
    for j = 1:1:numPan                                                      
        if (j == i)                                                         
            A(i,j) = 0;                                                     
        else                                                                
            A(i,j) = -K(i,j);                                               
        end
    end
end


b = zeros(numPan,1);                                                        
for i = 1:1:numPan                                                          
    b(i) = -Vinf*2*pi*cos(beta(i));                                         
end

% Satisfy the Kutta condition
pct    = 100;                                                               
panRep = floor((pct/100)*numPan);                                           
if (panRep == 0)                                                            
    panRep = 1;                                                             
end
A(panRep,:)   = 0;                                                          
A(panRep,1)   = 1;                                                          
A(panRep,end) = 1;                                                          
b(panRep)     = 0;                                                          

% Compute gamma values from system of equations
gamma = A\b;                                                                

%% COMPUTE PANEL VELOCITIES AND PRESSURE COEFFICIENTS

% Compute velocities on each panel
Vt = zeros(numPan,1);                                                       
Cp = zeros(numPan,1);                                                       
for i = 1:1:numPan                                                          
    addVal  = 0;                                                            
    for j = 1:1:numPan                                                      
        addVal = addVal - (gamma(j)/(2*pi))*L(i,j);                         
    end
    
    Vt(i) = Vinf*sin(beta(i)) + addVal + gamma(i)/2;                        
    Cp(i) = 1-(Vt(i)/Vinf)^2;                                               
end

%% COMPUTE LIFT AND MOMENT COEFFICIENTS

% Compute normal and axial force coefficients
CN = -Cp.*S.*sin(beta);                                                     % Normal force coefficient []
CA = -Cp.*S.*cos(beta);                                                     % Axial force coefficient []

% Compute lift and drag coefficients
CL = sum(CN.*cosd(AoA)) - sum(CA.*sind(AoA));                               % Decompose axial and normal to lift coefficient []
CM = sum(Cp.*(XC-0.25).*S.*cos(phi));                                       % Moment coefficient []

%% COMPUTE STREAMLINES - REF [4]

if (flagPlot(5) == 1 || flagPlot(6) == 1)                                   % If we are plotting 5 or 6
    % Grid parameters
    nGridX = 150;                                                           % X-grid for streamlines and contours
    nGridY = 150;                                                           % Y-grid for streamlines and contours
	xVals  = [-0.5; 1.5];                                                   % X-grid extents [min, max]
    yVals  = [-0.3; 0.3];                                                   % Y-grid extents [min, max]
    
    % Streamline parameters
    stepsize = 0.01;                                                        % Step size for streamline propagation
    maxVert  = nGridX*nGridY*100;                                           % Maximum vertices
    slPct    = 25;                                                          % Percentage of streamlines of the grid
    Ysl      = linspace(yVals(1),yVals(2),floor((slPct/100)*nGridY))';      % Create array of Y streamline starting points
    
    % Generate the grid points
    Xgrid   = linspace(xVals(1),xVals(2),nGridX)';                          % X-values in evenly spaced grid
    Ygrid   = linspace(yVals(1),yVals(2),nGridY)';                          % Y-values in evenly spaced grid
    [XX,YY] = meshgrid(Xgrid,Ygrid);                                        % Create meshgrid from X and Y grid arrays
    
    % Initialize velocities
    Vx = zeros(nGridX,nGridY);                                              % Initialize X-velocity matrix
    Vy = zeros(nGridX,nGridY);                                              % Initialize Y-velocity matrix
    
    % Solve for grid point X and Y velocities
    for m = 1:1:nGridX
        for n = 1:1:nGridY
            XP = XX(m,n);                                                   % Current iteration's X grid point
            YP = YY(m,n);                                                   % Current iteration's Y grid point
            [Nx,Ny] = STREAMLINE_VPM(XP,YP,XB,YB,phi,S);                    % Compute Nx and Ny geometric integrals
            
            [in,on] = inpolygon(XP,YP,XB,YB);
            if (in == 1 || on == 1)                                         % If the grid point is in or on the airfoil
                Vx(m,n) = 0;                                                % Set X-velocity equal to zero
                Vy(m,n) = 0;                                                % Set Y-velocity equal to zero
            else                                                            % If the grid point is outside the airfoil
                Vx(m,n) = Vinf*cosd(AoA) + sum(-gamma.*Nx./(2*pi));         % Compute X-velocity
                Vy(m,n) = Vinf*sind(AoA) + sum(-gamma.*Ny./(2*pi));         % Compute Y-velocity
            end
        end
    end
    
    % Compute grid point velocity magnitude and pressure coefficient
    Vxy  = sqrt(Vx.^2 + Vy.^2);                                             % Compute magnitude of velocity vector []
    CpXY = 1-(Vxy./Vinf).^2;                                                % Pressure coefficient []
end


%% PLOTTING

% FIGURE: Airfoil with panel normal vectors
if (flagPlot(1) == 1)
   % figure(1);                                                              % Create figure
     hold(app.UIAxes); grid off;                                                 % Get ready for plotting
    %set(gcf,'Color','White');                                               % Set color to white
    %set(gca,'FontSize',12);                                                 % Set font size
    fill(app.UIAxes,XB,YB,'k');                                                        % Plot airfoil
    for i = 1:1:numPan                                                      % Loop over all panels
        X(1) = XC(i);                                                       % Set X start of panel orientation vector
        X(2) = XC(i) + S(i)*cosd(betaD(i)+AoA);                             % Set X end of panel orientation vector
        Y(1) = YC(i);                                                       % Set Y start of panel orientation vector
        Y(2) = YC(i) + S(i)*sind(betaD(i)+AoA);                             % Set Y end of panel orientation vector
        plot(app.UIAxes2,X,Y,'r-','LineWidth',2);                                       % Plot panel normal vector
    end
    xlabel('X Units');                                                      % Set X-label
    ylabel('Y Units');                                                      % Set Y-label
	xlim('auto');                                                           % Set X-axis limits to auto
    ylim('auto');                                                           % Set Y-axis limits to auto
    axis equal;                                                             % Set axes equal
    zoom reset;                                                             % Reset zoom
end


% FIGURE: Cp vectors at airfoil control points
if (flagPlot(3) == 1)
    %figure(3);                                                              % Create figure
    cla; hold(app.UIAxes4); grid(app.UIAxes4);                                                  % Get ready for plotting
    %set(gcf,'Color','White');                                               % Set color to white
    %set(gca,'FontSize',12);                                                 % Set font size
    Cps = abs(Cp*0.25);                                                     % Scale and make positive all Cp values
    for i = 1:1:length(Cps)                                                 % Loop over all panels
        X(1) = XC(i);                                                       % Control point X-coordinate
        X(2) = XC(i) + Cps(i)*cosd(betaD(i)+AoA);                           % Ending X-value based on Cp magnitude
        Y(1) = YC(i);                                                       % Control point Y-coordinate
        Y(2) = YC(i) + Cps(i)*sind(betaD(i)+AoA);                           % Ending Y-value based on Cp magnitude
        
        if (Cp(i) < 0)                                                      % If pressure coefficient is negative
            p{1} = plot(app.UIAxes4,X,Y,'r-','LineWidth',2);                                   % Plot as a red line
        elseif (Cp(i) >= 0)                                                 % If pressure coefficient is zero or positive
            p{2} = plot(app.UIAxes4,X,Y,'b-','LineWidth',2);                                   % Plot as a blue line
        end
    end
    fill(app.UIAxes4,XB,YB,'k');                                                        % Plot the airfoil as black polygon
	legend(app.UIAxes4,[p{1},p{2}],{'Negative Cp','Positive Cp'});                      % Show legend
    xlabel(app.UIAxes4,'X Units');                                                      % Set X-label
    ylabel(app.UIAxes,'Y Units');                                                      % Set Y-label
    %xlim('auto');                                                           % Set X-axis limits to auto
    %ylim('auto');                                                           % Set Y-axis limits to auto
    %axis equal;                                                             % Set axes equal
    %zoom reset;                                                             % Reset zoom
end

% FIGURE: Pressure coefficient comparison (XFOIL vs. VPM)
if (flagPlot(4) == 1)
    %figure(4);                                                              % Create figure
     hold(app.UIAxes3); grid(app.UIAxes3);                                                  % Get ready for plotting
%    set(app.UIAxes3,gcf,'Color','White');                                               % Set color to white
 %   set(app.UIAxes3,gca,'FontSize',12);                                                 % Set font size
    midIndX = floor(length(xFoilCP)/2);                                     % Airfoil middle index for XFOIL data
    midIndS = floor(length(Cp)/2);                                          % Airfoil middle index for SPM data
    pXu = plot(app.UIAxes3,xFoilX(1:midIndX),xFoilCP(1:midIndX),'b-','LineWidth',2);    % Plot Cp for upper surface of airfoil from XFOIL
    pXl = plot(app.UIAxes3,xFoilX(midIndX+1:end),xFoilCP(midIndX+1:end),'r-',...        % Plot Cp for lower surface of airfoil from XFOIL
                    'LineWidth',2);
    pVl = plot(app.UIAxes3,XC(1:midIndS),Cp(1:midIndS),'b--o','MarkerFaceColor','r');     % Plot Cp for upper surface of airfoil from SPM
    pVu = plot(app.UIAxes3,XC(midIndS+1:end),Cp(midIndS+1:end),'r--o',...                 % Plot Cp for lower surface of airfoil from SPM
                    'MarkerFaceColor','b');
    legend(app.UIAxes3,[pXu,pXl,pVu,pVl],...                                            % Show legend
         {'XFOIL Upper','XFOIL Lower','VPM Upper','VPM Lower'});
    xlabel(app.UIAxes3,'X Coordinate');                                                 % Set X-label
    ylabel(app.UIAxes3,'Cp');                                                           % Set Y-label
    xlim(app.UIAxes3,[0 1]);                                                            % Set X-axis limits
    ylim(app.UIAxes3,'auto');                                                           % Set Y-axis limits to auto
    %set(gca,'Ydir','reverse')                                               % Reverse direction of Y-axis
    title(app.UIAxes3,['Airfoil: ' xFoilResults.afName ...                              % Title
           ', CL_{VPM}/CL_{XFOIL} = ' ...
           num2str(CL,4) '/' num2str(xFoilCL,4)]);
      %zoom reset;                                                             % Reset zoom
end

% FIGURE: Airfoil streamlines
if (flagPlot(5) == 1)
    figure(5);                                                              % Create figure
    cla; hold on; grid on;                                                  % Get ready for plotting
    set(gcf,'Color','White');                                               % Set color to white
    set(gca,'FontSize',12);                                                 % Set font size
    for i = 1:1:length(Ysl)                                                 % Loop over all Y streamline starting points
        sl = streamline(XX,YY,Vx,Vy,xVals(1),Ysl(i),[stepsize,maxVert]);    % Plot the streamline
        set(sl,'LineWidth',2);                                              % Set streamline line width
    end
    fill(XB,YB,'k');                                                        % Plot airfoil as black polygon
	xlabel('X Units');                                                      % Set X-label
    ylabel('Y Units');                                                      % Set Y-label
    xlim(xVals);                                                            % Set X-axis limits
	axis equal;                                                             % Set axes equal
    ylim(yVals);                                                            % Set Y-axis limits
    zoom reset;                                                             % Reset zoom
end

% FIGURE: Pressure coefficient contour
if (flagPlot(6) == 1)
    %figure(6);                                                              % Create figure
     hold(app.UIAxes2); grid(app.UIAxes2);                                                  % Get ready for plotting
   % set(gcf,'Color','White');                                               % Set color to white
   %set(gca,'FontSize',12);                                                 % Set font size
    contourf(app.UIAxes2,XX,YY,CpXY,100,'EdgeColor','none');                            % Plot Cp contour
    fill(app.UIAxes2,XB,YB,'k');                                                        % Plot airfoil as black polygon
    xlabel(app.UIAxes2,'X Units');                                                      % Set X-label
    ylabel(app.UIAxes2,'Y Units');                                                      % Set Y-label
    xlim(app.UIAxes2,xVals);                                                            % Set X-axis limits
	axis equal;                                                             % Set axes equal
    ylim(app.UIAxes2,yVals);                                                            % Set Y-axis limits
    zoom reset;                                                             % Reset zoom
end


app.CLEditField.Value=CL;
app.CMEditField.Value=CM;
app.ComputationStatusLamp.Color='g';        


        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.AOAEditField.Value=0;
            app.VinfEditField.Value=0;
            app.NACAEditField.Value=0;
            app.NoofPanelsEditField.Value=0;
            app.CLEditField.Value=0;
            app.CMEditField.Value=0;
            close(app.UIFigure);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {497, 497, 497};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {497, 497};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {189, '1x', 254};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.FLUIDFLOWDETAILSLabel = uilabel(app.LeftPanel);
            app.FLUIDFLOWDETAILSLabel.Text = 'FLUID FLOW DETAILS';
            app.ComputationStatusLampLabel = uilabel(app.LeftPanel);
            app.ComputationStatusLampLabel.Text = 'Computation Status';
            app.ComputationStatusLamp = uilamp(app.LeftPanel);
            app.INPUTPanel = uipanel(app.LeftPanel);
            app.INPUTPanel.Title = 'INPUT';
            app.AOAEditFieldLabel = uilabel(app.INPUTPanel);
            app.AOAEditFieldLabel.Text = 'AOA';
            app.AOAEditField = uieditfield(app.INPUTPanel, 'numeric');
            app.VinfEditFieldLabel = uilabel(app.INPUTPanel);
            app.VinfEditFieldLabel.Text = 'Vinf';
            app.VinfEditField = uieditfield(app.INPUTPanel, 'numeric');
            app.NACAEditFieldLabel = uilabel(app.INPUTPanel);
            app.NACAEditFieldLabel.Text = 'NACA';
            app.NACAEditField = uieditfield(app.INPUTPanel, 'numeric');
            app.NoofPanelsEditFieldLabel = uilabel(app.INPUTPanel);
            app.NoofPanelsEditFieldLabel.Text = 'No of Panels';
            app.NoofPanelsEditField = uieditfield(app.INPUTPanel, 'numeric');
            app.ComputeButton = uibutton(app.INPUTPanel, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.ClearButton = uibutton(app.LeftPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Aerofoil Plot')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.CenterPanel);
            title(app.UIAxes2, 'Pressure Coeffecient Cp')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.CenterPanel);
            title(app.UIAxes3, 'Lift Coeffecient CL')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.CenterPanel);
            title(app.UIAxes4, 'Direction of Pressure')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            app.ANALYSISRESULTSLabel = uilabel(app.RightPanel);
            app.ANALYSISRESULTSLabel.Text = 'ANALYSIS RESULTS';
            app.ResultsPanel = uipanel(app.RightPanel);
            app.ResultsPanel.Title = 'Results';
            app.CLEditFieldLabel = uilabel(app.ResultsPanel);
            app.CLEditFieldLabel.Text = 'CL';
            app.CLEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.CMEditFieldLabel = uilabel(app.ResultsPanel);
            app.CMEditFieldLabel.Text = 'CM';
            app.CMEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.DownloadsPanel = uipanel(app.RightPanel);
            app.DownloadsPanel.Title = 'Downloads';
            app.PanelCoordinatesButton = uibutton(app.DownloadsPanel, 'push');
            app.PanelCoordinatesButton.Text = 'Panel Coordinates';
            app.VorticityMatrixButton = uibutton(app.DownloadsPanel, 'push');
            app.VorticityMatrixButton.Text = 'Vorticity Matrix';
            app.CpMatrixButton = uibutton(app.DownloadsPanel, 'push');
            app.CpMatrixButton.Text = 'Cp Matrix';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end