classdef mtp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        HomeTab                       matlab.ui.container.Tab
        Image                         matlab.ui.control.Image
        FLUIDFLOWANALYSISAROUNDANAEROFOILLabel  matlab.ui.control.Label
        NA30029Label                  matlab.ui.control.Label
        Panel_4                       matlab.ui.container.Panel
        Panel_6                       matlab.ui.container.Panel
        MTPPROJECTLabel               matlab.ui.control.Label
        BYLabel                       matlab.ui.control.Label
        PRATEEKKAUSHALLabel           matlab.ui.control.Label
        PROJECTDISCRIPTIONPanel       matlab.ui.container.Panel
        TextArea                      matlab.ui.control.TextArea
        INDIANINSTITUTEOFTECHNOLOGYKHARAGPURLabel  matlab.ui.control.Label
        Panel_7                       matlab.ui.container.Panel
        UNDERTHEGUIDANCELabel         matlab.ui.control.Label
        OFLabel                       matlab.ui.control.Label
        PROFRANADEVDATTALabel         matlab.ui.control.Label
        CreateAerofoilTab             matlab.ui.container.Tab
        INPUTPANELPanel               matlab.ui.container.Panel
        ComputationStatusLamp_2Label  matlab.ui.control.Label
        ComputationStatusLamp_2       matlab.ui.control.Lamp
        Panel_3                       matlab.ui.container.Panel
        NACAEditField_3Label          matlab.ui.control.Label
        NACAEditField_3               matlab.ui.control.NumericEditField
        ComputeButton_2               matlab.ui.control.Button
        NoofPanelsEditField_2Label    matlab.ui.control.Label
        NoofPanelsEditField_2         matlab.ui.control.NumericEditField
        TrailingEdgeButtonGroup       matlab.ui.container.ButtonGroup
        OpenButton                    matlab.ui.control.RadioButton
        ClosedButton                  matlab.ui.control.RadioButton
        GridTypeButtonGroup           matlab.ui.container.ButtonGroup
        UniformButton                 matlab.ui.control.RadioButton
        NonuniformButton              matlab.ui.control.RadioButton
        ChordLengthEditFieldLabel     matlab.ui.control.Label
        ChordLengthEditField          matlab.ui.control.NumericEditField
        OUTPUTPANELPanel              matlab.ui.container.Panel
        UITable                       matlab.ui.control.Table
        SAVECOORDINATESButton         matlab.ui.control.Button
        CLOSEButton                   matlab.ui.control.Button
        TabGroup3                     matlab.ui.container.TabGroup
        AerofoilPlotTab_2             matlab.ui.container.Tab
        UIAxes5                       matlab.ui.control.UIAxes
        CREATEAEROFOILSECTIONLabel    matlab.ui.control.Label
        NACAEditField_4Label          matlab.ui.control.Label
        NACAEditField_4               matlab.ui.control.NumericEditField
        FluidFlowAnalysisTab          matlab.ui.container.Tab
        Panel                         matlab.ui.container.Panel
        ComputationStatusLampLabel    matlab.ui.control.Label
        ComputationStatusLamp         matlab.ui.control.Lamp
        CloseButton                   matlab.ui.control.Button
        INPUTPanel                    matlab.ui.container.Panel
        AOAEditFieldLabel             matlab.ui.control.Label
        AOAEditField                  matlab.ui.control.NumericEditField
        VinfEditFieldLabel            matlab.ui.control.Label
        VinfEditField                 matlab.ui.control.NumericEditField
        NACAEditFieldLabel            matlab.ui.control.Label
        NACAEditField                 matlab.ui.control.NumericEditField
        ComputeButton                 matlab.ui.control.Button
        NoofPanelsEditFieldLabel      matlab.ui.control.Label
        NoofPanelsEditField           matlab.ui.control.NumericEditField
        Label                         matlab.ui.control.Label
        RAWDATAPanel                  matlab.ui.container.Panel
        TabGroup4                     matlab.ui.container.TabGroup
        PANELTab                      matlab.ui.container.Tab
        UITable2                      matlab.ui.control.Table
        VORTICITYTab                  matlab.ui.container.Tab
        UITable3                      matlab.ui.control.Table
        CPTab                         matlab.ui.container.Tab
        UITable4                      matlab.ui.control.Table
        TabGroup2                     matlab.ui.container.TabGroup
        AerofoilPlotTab               matlab.ui.container.Tab
        UIAxes                        matlab.ui.control.UIAxes
        PressureGradientTab           matlab.ui.container.Tab
        UIAxes2                       matlab.ui.control.UIAxes
        CPPlotTab                     matlab.ui.container.Tab
        UIAxes3                       matlab.ui.control.UIAxes
        PressureDirectionTab          matlab.ui.container.Tab
        UIAxes4                       matlab.ui.control.UIAxes
        FLUIDFLOWANALYSISLabel        matlab.ui.control.Label
        NACAEditField_2Label          matlab.ui.control.Label
        NACAEditField_2               matlab.ui.control.NumericEditField
        CMEditFieldLabel              matlab.ui.control.Label
        CMEditField                   matlab.ui.control.NumericEditField
        CLEditFieldLabel              matlab.ui.control.Label
        CLEditField                   matlab.ui.control.NumericEditField
        ParametricAnalysisTab         matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            load = 'Airfoil';                                                            
            AoA  = app.AOAEditField.Value;
            Vinf = app.VinfEditField.Value; 
            fileName = 'naca2420';
            aoa=app.AOAEditField.Value;
            vinf=app.VinfEditField.Value;
            %app.sumEditField.Value=aoa+vinf;
            
           % [XB,YB]  = LOAD_AIRFOIL_SELIG(app,fileName);


            flagAirfoil.XFoilCreate = 1;                                                
            flagAirfoil.XFoilLoad   = 0;   
            
            
flagPlot = [0;          % Airfoil with panel normal vectors
            0;          % Geometry boundary pts, control pts, first panel, second panel
            1;          % Cp vectors at airfoil surface panels
            1;          % Pressure coefficient comparison (XFOIL vs. VPM)
            0;          % Airfoil streamlines
            1];         % Pressure coefficient contour


            %Vinf = 1;                                                                   
            %AoA  = 0; 
            nn=app.NACAEditField.Value;
            pp=app.NoofPanelsEditField.Value;
            NACA = num2str(nn);                                                              
            alpha = AoA*(pi/180);  
            
            PPAR.N  = num2str(pp);                                                            % "Number of panel nodes"
            PPAR.P  = '4';                                                              % "Panel bunching parameter"
            PPAR.T  = '1.5';                                                            % "TE/LE panel density ratios"
            PPAR.R  = '1';                                                              % "Refined area/LE panel density ratio"
            PPAR.XT = '1 1';                                                            % "Top side refined area x/c limits"
            PPAR.XB = '1 1';
            
            
            [xFoilResults,success] = XFOIL(NACA,PPAR,AoA,flagAirfoil);                  % Get XFOIL results for prescribed airfoil

            afName  = xFoilResults.afName;                                              % Airfoil name
xFoilX  = xFoilResults.X;                                                   % X-coordinate for Cp result
xFoilY  = xFoilResults.Y;                                                   % Y-coordinate for Cp result
xFoilCP = xFoilResults.CP;                                                  % Pressure coefficient
XB      = xFoilResults.XB;                                                  % Boundary point X-coordinate
YB      = xFoilResults.YB;                                                  % Boundary point Y-coordinate
xFoilCL = xFoilResults.CL;                                                  % Lift coefficient
xFoilCD = xFoilResults.CD;                                                  % Drag coefficient
xFoilCM = xFoilResults.CM;                                                  % Moment coefficient


numPts = length(XB);                                                        
numPan = numPts - 1;                                                        
plot(app.UIAxes,XB,YB,'ko-');

%% CHECK PANEL DIRECTIONS - FLIP IF NECESSARY

% Check for direction of points
edge = zeros(numPan,1);                                                     
for i = 1:1:numPan                                                          
    edge(i) = (XB(i+1)-XB(i))*(YB(i+1)+YB(i));                              
end
sumEdge = sum(edge);                                                        
% If panels are CCW, flip them (don't if CW)
if (sumEdge < 0)                                                            
    XB = flipud(XB);                                                        
    YB = flipud(YB);                                                        
end

%% PANEL METHOD GEOMETRY - REF [1]

% Initialize variables
XC   = zeros(numPan,1);                                                    
YC   = zeros(numPan,1);                                                    
Cp_x=zeros(numPan,1);
S    = zeros(numPan,1);                                                    
phiD = zeros(numPan,1);                                                    

% Find geometric quantities of the airfoil
for i = 1:1:numPan                                                         
    XC(i)   = 0.5*(XB(i)+XB(i+1));                                         
    YC(i)   = 0.5*(YB(i)+YB(i+1));                                         
    dx      = XB(i+1)-XB(i);                                               
    dy      = YB(i+1)-YB(i);                                               
    S(i)    = (dx^2 + dy^2)^0.5;                                           
	phiD(i) = atan2d(dy,dx);                                               
    if (phiD(i) < 0)                                                       
        phiD(i) = phiD(i) + 360;
    end
end


deltaD             = phiD + 90;                                             
betaD              = deltaD - AoA;                                          
betaD(betaD > 360) = betaD(betaD > 360) - 360;                              

phi  = phiD.*(pi/180);                                                      
beta = betaD.*(pi/180);                                                     

%% COMPUTE VORTEX PANEL STRENGTHS - REF [5]


[K,L] = COMPUTE_KL_VPM(XC,YC,XB,YB,phi,S);                                  % Compute geometric integrals


A = zeros(numPan,numPan);                                                   
for i = 1:1:numPan                                                          
    for j = 1:1:numPan                                                      
        if (j == i)                                                         
            A(i,j) = 0;                                                     
        else                                                                
            A(i,j) = -K(i,j);                                               
        end
    end
end


b = zeros(numPan,1);                                                        
for i = 1:1:numPan                                                          
    b(i) = -Vinf*2*pi*cos(beta(i));                                         
end

% Satisfy the Kutta condition
pct    = 100;                                                               
panRep = floor((pct/100)*numPan);                                           
if (panRep == 0)                                                            
    panRep = 1;                                                             
end
A(panRep,:)   = 0;                                                          
A(panRep,1)   = 1;                                                          
A(panRep,end) = 1;                                                          
b(panRep)     = 0;                                                          

% Compute gamma values from system of equations
gamma = A\b;                                                                

%% COMPUTE PANEL VELOCITIES AND PRESSURE COEFFICIENTS

% Compute velocities on each panel
Vt = zeros(numPan,1);                                                       
Cp = zeros(numPan,1);                                                       
for i = 1:1:numPan                                                          
    addVal  = 0;                                                            
    for j = 1:1:numPan                                                      
        addVal = addVal - (gamma(j)/(2*pi))*L(i,j);                         
    end
    
    Vt(i) = Vinf*sin(beta(i)) + addVal + gamma(i)/2;                        
    Cp(i) = 1-(Vt(i)/Vinf)^2;                                               
end

%% COMPUTE LIFT AND MOMENT COEFFICIENTS

% Compute normal and axial force coefficients
CN = -Cp.*S.*sin(beta);                                                     % Normal force coefficient []
CA = -Cp.*S.*cos(beta);                                                     % Axial force coefficient []

% Compute lift and drag coefficients
CL = sum(CN.*cosd(AoA)) - sum(CA.*sind(AoA));                               % Decompose axial and normal to lift coefficient []
CM = sum(Cp.*(XC-0.25).*S.*cos(phi));                                       % Moment coefficient []

%% COMPUTE STREAMLINES - REF [4]

if (flagPlot(5) == 1 || flagPlot(6) == 1)                                   % If we are plotting 5 or 6
    % Grid parameters
    nGridX = 150;                                                           % X-grid for streamlines and contours
    nGridY = 150;                                                           % Y-grid for streamlines and contours
	xVals  = [-0.5; 1.5];                                                   % X-grid extents [min, max]
    yVals  = [-0.3; 0.3];                                                   % Y-grid extents [min, max]
    
    % Streamline parameters
    stepsize = 0.01;                                                        % Step size for streamline propagation
    maxVert  = nGridX*nGridY*100;                                           % Maximum vertices
    slPct    = 25;                                                          % Percentage of streamlines of the grid
    Ysl      = linspace(yVals(1),yVals(2),floor((slPct/100)*nGridY))';      % Create array of Y streamline starting points
    
    % Generate the grid points
    Xgrid   = linspace(xVals(1),xVals(2),nGridX)';                          % X-values in evenly spaced grid
    Ygrid   = linspace(yVals(1),yVals(2),nGridY)';                          % Y-values in evenly spaced grid
    [XX,YY] = meshgrid(Xgrid,Ygrid);                                        % Create meshgrid from X and Y grid arrays
    
    % Initialize velocities
    Vx = zeros(nGridX,nGridY);                                              % Initialize X-velocity matrix
    Vy = zeros(nGridX,nGridY);                                              % Initialize Y-velocity matrix
    
    % Solve for grid point X and Y velocities
    for m = 1:1:nGridX
        for n = 1:1:nGridY
            XP = XX(m,n);                                                   % Current iteration's X grid point
            YP = YY(m,n);                                                   % Current iteration's Y grid point
            [Nx,Ny] = STREAMLINE_VPM(XP,YP,XB,YB,phi,S);                    % Compute Nx and Ny geometric integrals
            
            [in,on] = inpolygon(XP,YP,XB,YB);
            if (in == 1 || on == 1)                                         % If the grid point is in or on the airfoil
                Vx(m,n) = 0;                                                % Set X-velocity equal to zero
                Vy(m,n) = 0;                                                % Set Y-velocity equal to zero
            else                                                            % If the grid point is outside the airfoil
                Vx(m,n) = Vinf*cosd(AoA) + sum(-gamma.*Nx./(2*pi));         % Compute X-velocity
                Vy(m,n) = Vinf*sind(AoA) + sum(-gamma.*Ny./(2*pi));         % Compute Y-velocity
            end
        end
    end
    
    % Compute grid point velocity magnitude and pressure coefficient
    Vxy  = sqrt(Vx.^2 + Vy.^2);                                             % Compute magnitude of velocity vector []
    CpXY = 1-(Vxy./Vinf).^2;                                                % Pressure coefficient []
end


%% PLOTTING

% FIGURE: Airfoil with panel normal vectors
if (flagPlot(1) == 1)
   % figure(1);                                                              % Create figure
     hold(app.UIAxes); grid off;                                                 % Get ready for plotting
    %set(gcf,'Color','White');                                               % Set color to white
    %set(gca,'FontSize',12);                                                 % Set font size
    fill(app.UIAxes,XB,YB,'k');                                                        % Plot airfoil
    for i = 1:1:numPan                                                      % Loop over all panels
        X(1) = XC(i);                                                       % Set X start of panel orientation vector
        X(2) = XC(i) + S(i)*cosd(betaD(i)+AoA);                             % Set X end of panel orientation vector
        Y(1) = YC(i);                                                       % Set Y start of panel orientation vector
        Y(2) = YC(i) + S(i)*sind(betaD(i)+AoA);                             % Set Y end of panel orientation vector
        plot(app.UIAxes2,X,Y,'r-','LineWidth',2);                                       % Plot panel normal vector
    end
    xlabel('X Units');                                                      % Set X-label
    ylabel('Y Units');                                                      % Set Y-label
	xlim('auto');                                                           % Set X-axis limits to auto
    ylim('auto');                                                           % Set Y-axis limits to auto
    axis equal;                                                             % Set axes equal
    zoom reset;                                                             % Reset zoom
end


% FIGURE: Cp vectors at airfoil control points
if (flagPlot(3) == 1)
    %figure(3);                                                              % Create figure
    cla; hold(app.UIAxes4); grid(app.UIAxes4);                                                  % Get ready for plotting
    %set(gcf,'Color','White');                                               % Set color to white
    %set(gca,'FontSize',12);                                                 % Set font size
    Cps = abs(Cp*0.25);                                                     % Scale and make positive all Cp values
    for i = 1:1:length(Cps)                                                 % Loop over all panels
        X(1) = XC(i);                                                       % Control point X-coordinate
        X(2) = XC(i) + Cps(i)*cosd(betaD(i)+AoA);                           % Ending X-value based on Cp magnitude
        Y(1) = YC(i);                                                       % Control point Y-coordinate
        Y(2) = YC(i) + Cps(i)*sind(betaD(i)+AoA);                           % Ending Y-value based on Cp magnitude
        
        if (Cp(i) < 0)                                                      % If pressure coefficient is negative
            p{1} = plot(app.UIAxes4,X,Y,'r-','LineWidth',2);                                   % Plot as a red line
        elseif (Cp(i) >= 0)                                                 % If pressure coefficient is zero or positive
            p{2} = plot(app.UIAxes4,X,Y,'b-','LineWidth',2);                                   % Plot as a blue line
        end
    end
    fill(app.UIAxes4,XB,YB,'k');                                                        % Plot the airfoil as black polygon
	%legend(app.UIAxes4,[p{1},p{2}],{'Negative Cp','Positive Cp'});                      % Show legend
    xlabel(app.UIAxes4,'X Units');                                                      % Set X-label
    ylabel(app.UIAxes,'Y Units');                                                      % Set Y-label
    %xlim('auto');                                                           % Set X-axis limits to auto
    %ylim('auto');                                                           % Set Y-axis limits to auto
    %axis equal;                                                             % Set axes equal
    %zoom reset;                                                             % Reset zoom
end

% FIGURE: Pressure coefficient comparison (XFOIL vs. VPM)
if (flagPlot(4) == 1)
    %figure(4);                                                              % Create figure
     hold(app.UIAxes3); grid(app.UIAxes3);                                                  % Get ready for plotting
%    set(app.UIAxes3,gcf,'Color','White');                                               % Set color to white
 %   set(app.UIAxes3,gca,'FontSize',12);                                                 % Set font size
    midIndX = floor(length(xFoilCP)/2);                                     % Airfoil middle index for XFOIL data
    midIndS = floor(length(Cp)/2);                                          % Airfoil middle index for SPM data
    pXu = plot(app.UIAxes3,xFoilX(1:midIndX),xFoilCP(1:midIndX),'b-','LineWidth',2);    % Plot Cp for upper surface of airfoil from XFOIL
    pXl = plot(app.UIAxes3,xFoilX(midIndX+1:end),xFoilCP(midIndX+1:end),'r-',...        % Plot Cp for lower surface of airfoil from XFOIL
                    'LineWidth',2);
    pVl = plot(app.UIAxes3,XC(1:midIndS),Cp(1:midIndS),'b--o','MarkerFaceColor','r');     % Plot Cp for upper surface of airfoil from SPM
    pVu = plot(app.UIAxes3,XC(midIndS+1:end),Cp(midIndS+1:end),'r--o',...                 % Plot Cp for lower surface of airfoil from SPM
                    'MarkerFaceColor','b');
    legend(app.UIAxes3,[pXu,pXl,pVu,pVl],...                                            % Show legend
         {'XFOIL Upper','XFOIL Lower','VPM Upper','VPM Lower'});
    xlabel(app.UIAxes3,'X Coordinate');                                                 % Set X-label
    ylabel(app.UIAxes3,'Cp');                                                           % Set Y-label
    xlim(app.UIAxes3,[0 1]);                                                            % Set X-axis limits
    ylim(app.UIAxes3,'auto');                                                           % Set Y-axis limits to auto
    %set(gca,'Ydir','reverse')                                               % Reverse direction of Y-axis
    title(app.UIAxes3,['Airfoil: ' xFoilResults.afName ...                              % Title
           ', CL_{VPM}/CL_{XFOIL} = ' ...
           num2str(CL,4) '/' num2str(xFoilCL,4)]);
      %zoom reset;                                                             % Reset zoom
end

% FIGURE: Airfoil streamlines
if (flagPlot(5) == 1)
    figure(5);                                                              % Create figure
    cla; hold on; grid on;                                                  % Get ready for plotting
    set(gcf,'Color','White');                                               % Set color to white
    set(gca,'FontSize',12);                                                 % Set font size
    for i = 1:1:length(Ysl)                                                 % Loop over all Y streamline starting points
        sl = streamline(XX,YY,Vx,Vy,xVals(1),Ysl(i),[stepsize,maxVert]);    % Plot the streamline
        set(sl,'LineWidth',2);                                              % Set streamline line width
    end
    fill(XB,YB,'k');                                                        % Plot airfoil as black polygon
	xlabel('X Units');                                                      % Set X-label
    ylabel('Y Units');                                                      % Set Y-label
    xlim(xVals);                                                            % Set X-axis limits
	axis equal;                                                             % Set axes equal
    ylim(yVals);                                                            % Set Y-axis limits
    zoom reset;                                                             % Reset zoom
end

% FIGURE: Pressure coefficient contour
if (flagPlot(6) == 1)
    %figure(6);                                                              % Create figure
     hold(app.UIAxes2); grid(app.UIAxes2);                                                  % Get ready for plotting
   % set(gcf,'Color','White');                                               % Set color to white
   %set(gca,'FontSize',12);                                                 % Set font size
    contourf(app.UIAxes2,XX,YY,CpXY,100,'EdgeColor','none');                            % Plot Cp contour
    fill(app.UIAxes2,XB,YB,'k');                                                        % Plot airfoil as black polygon
    xlabel(app.UIAxes2,'X Units');                                                      % Set X-label
    ylabel(app.UIAxes2,'Y Units');                                                      % Set Y-label
    xlim(app.UIAxes2,xVals);                                                            % Set X-axis limits
	axis equal;                                                             % Set axes equal
    ylim(app.UIAxes2,yVals);                                                            % Set Y-axis limits
    zoom reset;                                                             % Reset zoom
end

app.UITable2.Data=[XC,YC,phiD,S];
app.UITable3.Data=[XC,YC,gamma];
app.UITable4.Data=[XC,YC,Cp];


app.CLEditField.Value=CL;
app.CMEditField.Value=CM;
app.ComputationStatusLamp.Color='g';        
  app.NACAEditField_2.Value=app.NACAEditField.Value;          
            
            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
             
            close(app.UIFigure);
        end

        % Button pushed function: ComputeButton_2
        function ComputeButton_2Pushed(app, event)
            %Input Parameters
naca=app.NACAEditField_3.Value;

TE=1;                       % 1 for open trailing edge and 2 for closed trailing edge
gridPoints=app.NoofPanelsEditField_2.Value;

gridtype=1;                     %1 for uniform and 2 for non-uniform
c=app.ChordLengthEditField.Value;


m=floor(naca/1000);
p=rem(floor((naca/100)),10);
t=rem(naca,100);


M = m/100;
P = p/10;
T = t/100;

a0 = 0.2969;
a1 = -0.1260;
a2 = -0.3516;
a3 = 0.2843;
if (TE == 1)
    a4 = -0.1015;                                                        % Open trailing edge
elseif (TE == 2)
    a4 = -0.1036;                                                           % Closed trailing edge
end


%X coordinate points
if (gridtype == 1)                                                       % Uniform spacing
    x = linspace(0,1,gridPoints)';
elseif (gridtype == 2)                                                   % Non-uniform spacing
    beta = linspace(0,pi,gridPoints)';
    x = (0.5*(1-cos(beta)));
end

% Camber line and camber line gradient
yc     = ones(gridPoints,1);
dyc_dx = ones(gridPoints,1);
theta  = ones(gridPoints,1);
for i = 1:1:gridPoints
    if (x(i) >= 0 && x(i) < P)
        yc(i)     = (M/P^2)*((2*P*x(i))-x(i)^2);
        dyc_dx(i) = ((2*M)/(P^2))*(P-x(i));
    elseif (x(i) >=P && x(i) <=1)
        yc(i)     = (M/(1-P)^2)*(1-(2*P)+(2*P*x(i))-(x(i)^2));
        dyc_dx(i) = ((2*M)/((1-P)^2))*(P-x(i));
    end
    theta(i) = atan(dyc_dx(i));
end


% Thickness distribution
yt = 5*T.*((a0.*sqrt(x)) + (a1.*x) + (a2.*x.^2) + (a3.*x.^3) + (a4.*x.^4));

% Upper surface points
xu = x(:)  - yt(:).*sin(theta);
yu = yc(:) + yt(:).*cos(theta);

% Lower surface points
xl = x(:) + yt(:).*sin(theta);
yl = yc(:) - yt(:).*cos(theta);

% Camber line X points
xc = x;

x_ae=xl(end:-1:1);
y_ae=yl(end:-1:1);

x_f=[xu ; x_ae];
y_f=[yu ; y_ae];
%figure(1);
        cla(app.UIAxes5,"reset"); hold(app.UIAxes5); grid(app.UIAxes5);
        %set(gcf,'Color','White');
         plot(app.UIAxes5,x_f,y_f,'k-'); 
         % plot(app.UIAxes5,xl,yl,'k-');
          xlim(app.UIAxes5,[0 1]);
          %legend([p1,p2],{'Data 1','Data 2'});                                      % Add legend
xlabel(app.UIAxes5,'X Units');                                                          % Set X-label
ylabel(app.UIAxes5,'Y Units');

% Set Y-label
axis(app.UIAxes5,"equal");                                                                 % Set axes equal
zoom(app.UIAxes5,"reset"); 

app.UITable.Data=[x_f,y_f];


app.NACAEditField_4.Value=app.NACAEditField_3.Value;
app.ComputationStatusLamp_2.Color='g';
          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.Image = uiimage(app.HomeTab);
            app.Image.ImageSource = 'kgp.gif';
            app.FLUIDFLOWANALYSISAROUNDANAEROFOILLabel = uilabel(app.HomeTab);
            app.FLUIDFLOWANALYSISAROUNDANAEROFOILLabel.Text = 'FLUID FLOW ANALYSIS AROUND AN AEROFOIL';
            app.NA30029Label = uilabel(app.HomeTab);
            app.NA30029Label.Text = '16NA30029';
            app.Panel_4 = uipanel(app.HomeTab);
            app.Panel_4.Title = ' ';
            app.Panel_6 = uipanel(app.Panel_4);
            app.Panel_6.Title = ' ';
            app.MTPPROJECTLabel = uilabel(app.Panel_6);
            app.MTPPROJECTLabel.Text = 'MTP PROJECT';
            app.BYLabel = uilabel(app.Panel_6);
            app.BYLabel.Text = 'BY';
            app.PRATEEKKAUSHALLabel = uilabel(app.Panel_6);
            app.PRATEEKKAUSHALLabel.Text = 'PRATEEK KAUSHAL';
            app.PROJECTDISCRIPTIONPanel = uipanel(app.HomeTab);
            app.PROJECTDISCRIPTIONPanel.Title = 'PROJECT DISCRIPTION';
            app.TextArea = uitextarea(app.PROJECTDISCRIPTIONPanel);
            app.TextArea.Value = {'Lorem Ipsum is simply dummy text of the printing and typesetting industry. Lorem Ipsum has been the industry''s standard dummy text ever since the 1500s, when an unknown printer took a galley of type and scrambled it to make a type specimen book. It has survived not only five centuries, but also the leap into electronic typesetting, remaining essentially unchanged. It was popularised in the 1960s with the release of Letraset sheets containing Lorem Ipsum passages, and more recently with desktop publishing software like Aldus PageMaker including versions of Lorem Ipsum.'; ''; ''};
            app.INDIANINSTITUTEOFTECHNOLOGYKHARAGPURLabel = uilabel(app.HomeTab);
            app.INDIANINSTITUTEOFTECHNOLOGYKHARAGPURLabel.Text = 'INDIAN INSTITUTE OF TECHNOLOGY, KHARAGPUR';
            app.Panel_7 = uipanel(app.HomeTab);
            app.Panel_7.Title = ' ';
            app.UNDERTHEGUIDANCELabel = uilabel(app.Panel_7);
            app.UNDERTHEGUIDANCELabel.Text = 'UNDER THE GUIDANCE';
            app.OFLabel = uilabel(app.Panel_7);
            app.OFLabel.Text = 'OF';
            app.PROFRANADEVDATTALabel = uilabel(app.Panel_7);
            app.PROFRANADEVDATTALabel.Text = 'PROF. RANADEV DATTA';
            app.CreateAerofoilTab = uitab(app.TabGroup);
            app.CreateAerofoilTab.Title = 'Create Aerofoil';
            app.INPUTPANELPanel = uipanel(app.CreateAerofoilTab);
            app.INPUTPANELPanel.Title = 'INPUT PANEL';
            app.ComputationStatusLamp_2Label = uilabel(app.INPUTPANELPanel);
            app.ComputationStatusLamp_2Label.Text = 'Computation Status';
            app.ComputationStatusLamp_2 = uilamp(app.INPUTPANELPanel);
            app.Panel_3 = uipanel(app.CreateAerofoilTab);
            app.Panel_3.Title = ' ';
            app.NACAEditField_3Label = uilabel(app.Panel_3);
            app.NACAEditField_3Label.Text = 'NACA';
            app.NACAEditField_3 = uieditfield(app.Panel_3, 'numeric');
            app.NACAEditField_3.Value = 2420;
            app.ComputeButton_2 = uibutton(app.Panel_3, 'push');
            app.ComputeButton_2.ButtonPushedFcn = createCallbackFcn(app, @ComputeButton_2Pushed, true);
            app.ComputeButton_2.Text = 'Compute';
            app.NoofPanelsEditField_2Label = uilabel(app.Panel_3);
            app.NoofPanelsEditField_2Label.Text = 'No of Panels';
            app.NoofPanelsEditField_2 = uieditfield(app.Panel_3, 'numeric');
            app.NoofPanelsEditField_2.Value = 200;
            app.TrailingEdgeButtonGroup = uibuttongroup(app.Panel_3);
            app.TrailingEdgeButtonGroup.Title = 'Trailing Edge';
            app.OpenButton = uiradiobutton(app.TrailingEdgeButtonGroup);
            app.OpenButton.Text = 'Open';
            app.OpenButton.Value = true;
            app.ClosedButton = uiradiobutton(app.TrailingEdgeButtonGroup);
            app.ClosedButton.Text = 'Closed';
            app.GridTypeButtonGroup = uibuttongroup(app.Panel_3);
            app.GridTypeButtonGroup.Title = 'Grid Type';
            app.UniformButton = uiradiobutton(app.GridTypeButtonGroup);
            app.UniformButton.Text = 'Uniform';
            app.UniformButton.Value = true;
            app.NonuniformButton = uiradiobutton(app.GridTypeButtonGroup);
            app.NonuniformButton.Text = 'Non-uniform';
            app.ChordLengthEditFieldLabel = uilabel(app.Panel_3);
            app.ChordLengthEditFieldLabel.Text = 'Chord Length';
            app.ChordLengthEditField = uieditfield(app.Panel_3, 'numeric');
            app.ChordLengthEditField.Value = 1;
            app.OUTPUTPANELPanel = uipanel(app.CreateAerofoilTab);
            app.OUTPUTPANELPanel.Title = 'OUTPUT PANEL';
            app.UITable = uitable(app.OUTPUTPANELPanel);
            app.UITable.ColumnName = {'X'; 'Y'};
            app.UITable.RowName = {};
            app.SAVECOORDINATESButton = uibutton(app.OUTPUTPANELPanel, 'push');
            app.SAVECOORDINATESButton.Text = 'SAVE COORDINATES';
            app.CLOSEButton = uibutton(app.OUTPUTPANELPanel, 'push');
            app.CLOSEButton.Text = 'CLOSE';
            app.TabGroup3 = uitabgroup(app.CreateAerofoilTab);
            app.AerofoilPlotTab_2 = uitab(app.TabGroup3);
            app.AerofoilPlotTab_2.Title = 'Aerofoil Plot';
            app.UIAxes5 = uiaxes(app.AerofoilPlotTab_2);
            title(app.UIAxes5, 'NACA 2420')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.CREATEAEROFOILSECTIONLabel = uilabel(app.CreateAerofoilTab);
            app.CREATEAEROFOILSECTIONLabel.Text = 'CREATE AEROFOIL SECTION';
            app.NACAEditField_4Label = uilabel(app.CreateAerofoilTab);
            app.NACAEditField_4Label.Text = 'NACA';
            app.NACAEditField_4 = uieditfield(app.CreateAerofoilTab, 'numeric');
            app.FluidFlowAnalysisTab = uitab(app.TabGroup);
            app.FluidFlowAnalysisTab.Title = 'Fluid Flow Analysis';
            app.Panel = uipanel(app.FluidFlowAnalysisTab);
            app.Panel.Title = ' ';
            app.ComputationStatusLampLabel = uilabel(app.Panel);
            app.ComputationStatusLampLabel.Text = 'Computation Status';
            app.ComputationStatusLamp = uilamp(app.Panel);
            app.CloseButton = uibutton(app.Panel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.INPUTPanel = uipanel(app.Panel);
            app.INPUTPanel.Title = 'INPUT';
            app.AOAEditFieldLabel = uilabel(app.INPUTPanel);
            app.AOAEditFieldLabel.Text = 'AOA';
            app.AOAEditField = uieditfield(app.INPUTPanel, 'numeric');
            app.VinfEditFieldLabel = uilabel(app.INPUTPanel);
            app.VinfEditFieldLabel.Text = 'Vinf';
            app.VinfEditField = uieditfield(app.INPUTPanel, 'numeric');
            app.VinfEditField.Value = 1;
            app.NACAEditFieldLabel = uilabel(app.INPUTPanel);
            app.NACAEditFieldLabel.Text = 'NACA';
            app.NACAEditField = uieditfield(app.INPUTPanel, 'numeric');
            app.NACAEditField.Value = 2420;
            app.ComputeButton = uibutton(app.INPUTPanel, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.NoofPanelsEditFieldLabel = uilabel(app.INPUTPanel);
            app.NoofPanelsEditFieldLabel.Text = 'No of Panels';
            app.NoofPanelsEditField = uieditfield(app.INPUTPanel, 'numeric');
            app.NoofPanelsEditField.Value = 200;
            app.Label = uilabel(app.Panel);
            app.Label.Text = '';
            app.RAWDATAPanel = uipanel(app.FluidFlowAnalysisTab);
            app.RAWDATAPanel.Title = 'RAW DATA';
            app.TabGroup4 = uitabgroup(app.RAWDATAPanel);
            app.PANELTab = uitab(app.TabGroup4);
            app.PANELTab.Title = 'PANEL';
            app.UITable2 = uitable(app.PANELTab);
            app.UITable2.ColumnName = {'X'; 'Y'; 'angle'; 'panel size'};
            app.UITable2.RowName = {};
            app.VORTICITYTab = uitab(app.TabGroup4);
            app.VORTICITYTab.Title = 'VORTICITY';
            app.UITable3 = uitable(app.VORTICITYTab);
            app.UITable3.ColumnName = {'X'; 'Y'; 'gamma'};
            app.UITable3.RowName = {};
            app.CPTab = uitab(app.TabGroup4);
            app.CPTab.Title = 'CP';
            app.UITable4 = uitable(app.CPTab);
            app.UITable4.ColumnName = {'X'; 'Y'; 'Cp'};
            app.UITable4.RowName = {};
            app.TabGroup2 = uitabgroup(app.FluidFlowAnalysisTab);
            app.AerofoilPlotTab = uitab(app.TabGroup2);
            app.AerofoilPlotTab.Title = 'Aerofoil Plot';
            app.UIAxes = uiaxes(app.AerofoilPlotTab);
            title(app.UIAxes, 'Aerofoil Plot')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PressureGradientTab = uitab(app.TabGroup2);
            app.PressureGradientTab.Title = 'Pressure Gradient';
            app.UIAxes2 = uiaxes(app.PressureGradientTab);
            title(app.UIAxes2, 'Pressure Gradient')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.CPPlotTab = uitab(app.TabGroup2);
            app.CPPlotTab.Title = 'CP Plot';
            app.UIAxes3 = uiaxes(app.CPPlotTab);
            title(app.UIAxes3, 'Lift Coeffecient CL')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.PressureDirectionTab = uitab(app.TabGroup2);
            app.PressureDirectionTab.Title = 'Pressure Direction';
            app.UIAxes4 = uiaxes(app.PressureDirectionTab);
            title(app.UIAxes4, 'Direction of Pressure')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.FLUIDFLOWANALYSISLabel = uilabel(app.FluidFlowAnalysisTab);
            app.FLUIDFLOWANALYSISLabel.Text = 'FLUID FLOW ANALYSIS';
            app.NACAEditField_2Label = uilabel(app.FluidFlowAnalysisTab);
            app.NACAEditField_2Label.Text = 'NACA';
            app.NACAEditField_2 = uieditfield(app.FluidFlowAnalysisTab, 'numeric');
            app.CMEditFieldLabel = uilabel(app.FluidFlowAnalysisTab);
            app.CMEditFieldLabel.Text = 'CM';
            app.CMEditField = uieditfield(app.FluidFlowAnalysisTab, 'numeric');
            app.CLEditFieldLabel = uilabel(app.FluidFlowAnalysisTab);
            app.CLEditFieldLabel.Text = 'CL';
            app.CLEditField = uieditfield(app.FluidFlowAnalysisTab, 'numeric');
            app.ParametricAnalysisTab = uitab(app.TabGroup);
            app.ParametricAnalysisTab.Title = 'Parametric Analysis';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mtp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end